classdef automatic_sorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeSortingDisplayUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ParameterAdjustmentTab          matlab.ui.container.Tab
        standarddeviationcutoffEditField  matlab.ui.control.NumericEditField
        standarddeviationcutoffEditFieldLabel  matlab.ui.control.Label
        flagthresholdEditField          matlab.ui.control.NumericEditField
        flagthresholdLabel              matlab.ui.control.Label
        cutofffrequencyHzEditField      matlab.ui.control.NumericEditField
        cutofffrequencyHzEditFieldLabel  matlab.ui.control.Label
        refractoryperiodmsTextArea      matlab.ui.control.TextArea
        refractoryperiodmsTextAreaLabel  matlab.ui.control.Label
        threshold_to_mergeTextArea      matlab.ui.control.TextArea
        threshold_to_mergeTextAreaLabel  matlab.ui.control.Label
        wellselectionButton             matlab.ui.control.Button
        OtherParametersTab              matlab.ui.container.Tab
        overlapthresholdEditField       matlab.ui.control.NumericEditField
        overlapthresholdEditFieldLabel  matlab.ui.control.Label
        DTWFiguresCheckBox              matlab.ui.control.CheckBox
        SortingResultsCheckBox          matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        DataSelectionLabel              matlab.ui.control.Label
        Panel2_4                        matlab.ui.container.Panel
        filefolderTextArea              matlab.ui.control.TextArea
        filefolderTextAreaLabel         matlab.ui.control.Label
        fileselectionButton             matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        Panel2_6                        matlab.ui.container.Panel
        minelectrodesEditField          matlab.ui.control.NumericEditField
        minelectrodesEditFieldLabel     matlab.ui.control.Label
        maxISImsEditField_2             matlab.ui.control.NumericEditField
        maxISImsEditField_2Label        matlab.ui.control.Label
        minofspikesEditField_2          matlab.ui.control.NumericEditField
        minofspikesEditField_2Label     matlab.ui.control.Label
        BurstParametersLabel            matlab.ui.control.Label
        Panel2_5                        matlab.ui.container.Panel
        maxISImsEditField               matlab.ui.control.NumericEditField
        maxISImsEditFieldLabel          matlab.ui.control.Label
        minofspikesEditField            matlab.ui.control.NumericEditField
        minofspikesEditFieldLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.

    properties (Access = private)
        selectedWells = []; % To store the selected wells
    end

    methods (Access = private)

        % Helper functions for well selection
        function selectAll(app,checkboxes)
            for i = 1:numel(checkboxes)
                set(checkboxes(i), 'Value', 1);
            end
        end

        function clearAll(app,checkboxes)
            for i = 1:numel(checkboxes)
                set(checkboxes(i), 'Value', 0);
            end
        end

        function confirmSelection(app, checkboxes, wellFig)
            app.selectedWells = {};
            rows = 'ABCDEFGH';
            for r = 1:8
                for c = 1:12
                    if get(checkboxes(r, c), 'Value') == 1
                        wellName = [rows(r), num2str(c)];
                        app.selectedWells{end+1} = wellName;
                    end
                end
            end

            % Display selected wells
            if ~isempty(app.selectedWells)
                msgbox(['Selected wells: ' strjoin(app.selectedWells, ', ')], 'Selection Confirmed');
            else
                msgbox('No wells selected. All wells will be processed.', 'Info');
            end

            close(wellFig);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpikeSortingDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {284, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: fileselectionButton
        function fileselectionButtonPushed(app, event)
            folderpath = uigetdir('C:\');
            app.filefolderTextArea.Value = folderpath;
        end

        % Value changing function: filefolderTextArea
        function filefolderTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.filefolderTextArea.Value = changingValue;
        end

        % Callback function
        function minofSpikesTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.minofSpikesTextArea.Value = changingValue;
        end

        % Callback function
        function minspikeburstTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.minspikeburstTextArea.Value = changingValue;
        end

        % Value changing function: threshold_to_mergeTextArea
        function threshold_to_mergeTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.threshold_to_mergeTextArea.Value = changingValue;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.filefolderTextArea.Value{1}
            try

                % Call the original function with or without well selection
                if ~isempty(app.selectedWells)
                    % Process only selected wells
                    run_SAMS_with_selected_wells(app.filefolderTextArea.Value{1},...
                        app.selectedWells,...
                        app.threshold_to_mergeTextArea.Value{1},...
                        app.refractoryperiodmsTextArea.Value{1}, ...
                        app.minelectrodesEditField.Value,...
                        app.minofspikesEditField.Value, ...
                        app.maxISImsEditField.Value, ...
                        app.minofspikesEditField_2.Value, ...
                        app.maxISImsEditField_2.Value,...
                        app.cutofffrequencyHzEditField.Value,...
                        app.flagthresholdEditField.Value,...
                        app.standarddeviationcutoffEditField.Value,...
                        app.DTWFiguresCheckBox.Value,...
                        app.SortingResultsCheckBox.Value, ...
                        app.overlapthresholdEditField.Value);
                else
                    % Process all wells
                    run_SAMS(app.filefolderTextArea.Value{1},...
                        app.threshold_to_mergeTextArea.Value{1},...
                        app.refractoryperiodmsTextArea.Value{1}, ...
                        app.minelectrodesEditField.Value,...
                        app.minofspikesEditField.Value, ...
                        app.maxISImsEditField.Value, ...
                        app.minofspikesEditField_2.Value, ...
                        app.maxISImsEditField_2.Value,...
                        app.cutofffrequencyHzEditField.Value,...
                        app.flagthresholdEditField.Value,...
                        app.standarddeviationcutoffEditField.Value,...
                        app.DTWFiguresCheckBox.Value,...
                        app.SortingResultsCheckBox.Value, ...
                        app.overlapthresholdEditField.Value);
                end

            catch ME
                report = getReport(ME);
                fig = uifigure;
                uialert(fig,report,'Error Message','Interpreter','html');
                report
            end
        end

        % Value changing function: refractoryperiodmsTextArea
        function refractoryperiodmsTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.refractoryperiodmsTextArea.Value = changingValue;
        end

        % Callback function
        function networkactivitythresholdTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.networkactivitythresholdTextArea.Value = changingValue;
        end

        % Value changed function: minofspikesEditField
        function minofspikesEditFieldValueChanged(app, event)
            value = app.minofspikesEditField.Value;
            app.minofspikesEditField.Value = value;
        end

        % Button pushed function: wellselectionButton
        function wellselectionButtonPushed(app, event)
            % Create a figure for well selection
            wellFig = figure('Name', 'Select Wells', 'Position', [100, 100, 800, 350], ...
                'MenuBar', 'none', 'ToolBar', 'none', 'NumberTitle', 'off');

            % Create checkboxes for each well
            rows = 'ABCDEFGH';
            cols = 1:8;
            checkboxes = gobjects(8, 12); % 8 rows, 12 columns

            % Create checkbox grid
            for r = 1:8
                for c = 1:12
                    wellName = [rows(r), num2str(c)];
                    x = 50 + (c-1) * 50;
                    y = 300 - (r-1) * 35;
                    checkboxes(r, c) = uicontrol('Style', 'checkbox', ...
                        'String', wellName, ...
                        'Position', [x, y, 45, 20], ...
                        'Parent', wellFig);
                end
            end

            % Add Select All button
            selectAllBtn = uicontrol('Style', 'pushbutton', ...
                'String', 'Select All', ...
                'Position', [100, 20, 80, 30], ...
                'Parent', wellFig, ...
                'Callback', @(~,~) selectAll(app,checkboxes));

            % Add Clear All button
            clearAllBtn = uicontrol('Style', 'pushbutton', ...
                'String', 'Clear All', ...
                'Position', [200, 20, 80, 30], ...
                'Parent', wellFig, ...
                'Callback', @(~,~) clearAll(app,checkboxes));

            % Add OK button
            okBtn = uicontrol('Style', 'pushbutton', ...
                'String', 'OK', ...
                'Position', [300, 20, 60, 30], ...
                'Parent', wellFig, ...
                'Callback', @(~,~) confirmSelection(app, checkboxes, wellFig));

            % Add Cancel button
            cancelBtn = uicontrol('Style', 'pushbutton', ...
                'String', 'Cancel', ...
                'Position', [20, 20, 60, 30], ...
                'Parent', wellFig, ...
                'Callback', @(~,~) close(wellFig));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeSortingDisplayUIFigure = uifigure('Visible', 'off');
            app.SpikeSortingDisplayUIFigure.Name = 'SAMS';
            app.SpikeSortingDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpikeSortingDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'File';
            app.fileselectionButton = uibutton(app.Panel2_4, 'push');
            app.fileselectionButton.ButtonPushedFcn = createCallbackFcn(app, @fileselectionButtonPushed, true);
            app.fileselectionButton.Text = 'file selection';
            app.filefolderTextAreaLabel = uilabel(app.Panel2_4);
            app.filefolderTextAreaLabel.Text = 'file folder';
            app.filefolderTextArea = uitextarea(app.Panel2_4);
            app.filefolderTextArea.ValueChangingFcn = createCallbackFcn(app, @filefolderTextAreaValueChanging, true);
            app.DataSelectionLabel = uilabel(app.LeftPanel);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ParameterAdjustmentTab = uitab(app.TabGroup);
            app.ParameterAdjustmentTab.Title = 'Parameter Adjustment';
            app.wellselectionButton = uibutton(app.ParameterAdjustmentTab, 'push');
            app.wellselectionButton.ButtonPushedFcn = createCallbackFcn(app, @wellselectionButtonPushed, true);
            app.wellselectionButton.Text = 'well selection';
            app.threshold_to_mergeTextAreaLabel = uilabel(app.ParameterAdjustmentTab);
            app.threshold_to_mergeTextAreaLabel.Text = 'threshold_to_merge';
            app.threshold_to_mergeTextArea = uitextarea(app.ParameterAdjustmentTab);
            app.threshold_to_mergeTextArea.ValueChangingFcn = createCallbackFcn(app, @threshold_to_mergeTextAreaValueChanging, true);
            app.threshold_to_mergeTextArea.Value = {'1.5'};
            app.refractoryperiodmsTextAreaLabel = uilabel(app.ParameterAdjustmentTab);
            app.refractoryperiodmsTextAreaLabel.Text = 'refractory period (ms)';
            app.refractoryperiodmsTextArea = uitextarea(app.ParameterAdjustmentTab);
            app.refractoryperiodmsTextArea.ValueChangingFcn = createCallbackFcn(app, @refractoryperiodmsTextAreaValueChanging, true);
            app.refractoryperiodmsTextArea.Value = {'1.5'};
            app.cutofffrequencyHzEditFieldLabel = uilabel(app.ParameterAdjustmentTab);
            app.cutofffrequencyHzEditFieldLabel.Text = 'cutoff frequency (Hz)';
            app.cutofffrequencyHzEditField = uieditfield(app.ParameterAdjustmentTab, 'numeric');
            app.cutofffrequencyHzEditField.Value = 0.1;
            app.flagthresholdLabel = uilabel(app.ParameterAdjustmentTab);
            app.flagthresholdLabel.Text = 'flag threshold ';
            app.flagthresholdEditField = uieditfield(app.ParameterAdjustmentTab, 'numeric');
            app.flagthresholdEditField.Value = 0.2;
            app.standarddeviationcutoffEditFieldLabel = uilabel(app.ParameterAdjustmentTab);
            app.standarddeviationcutoffEditFieldLabel.Text = 'standard deviation cutoff';
            app.standarddeviationcutoffEditField = uieditfield(app.ParameterAdjustmentTab, 'numeric');
            app.standarddeviationcutoffEditField.Value = 3;
            app.OtherParametersTab = uitab(app.TabGroup);
            app.OtherParametersTab.Title = 'Other Parameters';
            app.SortingResultsCheckBox = uicheckbox(app.OtherParametersTab);
            app.SortingResultsCheckBox.Text = 'Sorting Results';
            app.SortingResultsCheckBox.Value = true;
            app.DTWFiguresCheckBox = uicheckbox(app.OtherParametersTab);
            app.DTWFiguresCheckBox.Text = 'DTW Figures';
            app.overlapthresholdEditFieldLabel = uilabel(app.OtherParametersTab);
            app.overlapthresholdEditFieldLabel.Text = 'overlap threshold';
            app.overlapthresholdEditField = uieditfield(app.OtherParametersTab, 'numeric');
            app.overlapthresholdEditField.Value = 0.7;
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel2_5 = uipanel(app.RightPanel);
            app.Panel2_5.Title = 'Single Electrode Bursts';
            app.minofspikesEditFieldLabel = uilabel(app.Panel2_5);
            app.minofspikesEditFieldLabel.Text = 'min # of spikes';
            app.minofspikesEditField = uieditfield(app.Panel2_5, 'numeric');
            app.minofspikesEditField.ValueChangedFcn = createCallbackFcn(app, @minofspikesEditFieldValueChanged, true);
            app.minofspikesEditField.Value = 5;
            app.maxISImsEditFieldLabel = uilabel(app.Panel2_5);
            app.maxISImsEditFieldLabel.Text = 'max ISI (ms)';
            app.maxISImsEditField = uieditfield(app.Panel2_5, 'numeric');
            app.maxISImsEditField.Value = 100;
            app.BurstParametersLabel = uilabel(app.RightPanel);
            app.BurstParametersLabel.Text = 'Burst Parameters';
            app.Panel2_6 = uipanel(app.RightPanel);
            app.Panel2_6.Title = 'Network Bursts';
            app.minofspikesEditField_2Label = uilabel(app.Panel2_6);
            app.minofspikesEditField_2Label.Text = 'min # of spikes';
            app.minofspikesEditField_2 = uieditfield(app.Panel2_6, 'numeric');
            app.minofspikesEditField_2.Value = 50;
            app.maxISImsEditField_2Label = uilabel(app.Panel2_6);
            app.maxISImsEditField_2Label.Text = 'max ISI (ms)';
            app.maxISImsEditField_2 = uieditfield(app.Panel2_6, 'numeric');
            app.maxISImsEditField_2.Value = 100;
            app.minelectrodesEditFieldLabel = uilabel(app.Panel2_6);
            app.minelectrodesEditFieldLabel.Text = 'min electrodes(%)';
            app.minelectrodesEditField = uieditfield(app.Panel2_6, 'numeric');
            app.minelectrodesEditField.Value = 35;
            % Show the figure after all components are created
            app.SpikeSortingDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = automatic_sorting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpikeSortingDisplayUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeSortingDisplayUIFigure)
        end
    end
end